classdef KS_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlayButton                      matlab.ui.control.Button
        KarplusStrongAlgorithmLabel     matlab.ui.control.Label
        SinglenotesynthesisLabel        matlab.ui.control.Label
        ChordsynthesisAcousticsonlyLabel  matlab.ui.control.Label
        AutomaticcompositionAcousticsonlyLabel  matlab.ui.control.Label
        CButton                         matlab.ui.control.Button
        DmButton                        matlab.ui.control.Button
        EmButton                        matlab.ui.control.Button
        FButton                         matlab.ui.control.Button
        GButton                         matlab.ui.control.Button
        AmButton                        matlab.ui.control.Button
        BButton                         matlab.ui.control.Button
        PlayingSkillSynthesisAcousticsonlyLabel  matlab.ui.control.Label
        SlurButton                      matlab.ui.control.Button
        GlissandiButton                 matlab.ui.control.Button
        VibratoButton                   matlab.ui.control.Button
        RandomChordslineLabel           matlab.ui.control.Label
        RandomMusicLabel                matlab.ui.control.Label
        GuitartypeSwitchLabel           matlab.ui.control.Label
        GuitartypeSwitch                matlab.ui.control.Switch
        Frequencyofthe1stnoteEditFieldLabel  matlab.ui.control.Label
        Frequencyofthe1stnoteEditField  matlab.ui.control.NumericEditField
        dynamicsparameterKnobLabel      matlab.ui.control.Label
        dynamicsparameterKnob           matlab.ui.control.Knob
        decaystretchingKnobLabel        matlab.ui.control.Label
        decaystretchingKnob             matlab.ui.control.Knob
        Frequencyofthe2ndnoteEditFieldLabel  matlab.ui.control.Label
        Frequencyofthe2ndnoteEditField  matlab.ui.control.NumericEditField
        BPMEditFieldLabel               matlab.ui.control.Label
        BPMEditField                    matlab.ui.control.NumericEditField
        syncopatednote1EditFieldLabel   matlab.ui.control.Label
        syncopatednote1EditField        matlab.ui.control.NumericEditField
        syncopatednote2EditFieldLabel   matlab.ui.control.Label
        syncopatednote2EditField        matlab.ui.control.NumericEditField
        PleaseputyouraudiofileandAppinthesamefolderLabel_2  matlab.ui.control.Label
        ChordnumberEditFieldLabel       matlab.ui.control.Label
        ChordnumberEditField            matlab.ui.control.NumericEditField
        BarnumberEditFieldLabel         matlab.ui.control.Label
        BarnumberEditField              matlab.ui.control.NumericEditField
        PlayButton_2                    matlab.ui.control.Button
        MinimalsegementEditFieldLabel   matlab.ui.control.Label
        MinimalsegementEditField        matlab.ui.control.NumericEditField
        Chordtype0forMajorand1forMinorLabel  matlab.ui.control.Label
        Chordtype0forMajorand1forMinorEditField  matlab.ui.control.NumericEditField
        PlayButton_3                    matlab.ui.control.Button
        MinimalsegementEditField_2Label  matlab.ui.control.Label
        MinimalsegementEditField_2      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        Fs = 44100;  % Description
        Mu = 0.5;
        D = 0.005;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            switch app.GuitartypeSwitch.Value
                case 'Acoustics'
                 y = KS_singlenotes_acoustics(app.BPMEditField.Value,app.syncopatednote1EditField.Value,app.Frequencyofthe1stnoteEditField.Value,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
                case 'Electric'
                 y = KS_singlenotes_electric(app.BPMEditField.Value,app.syncopatednote1EditField.Value,app.Frequencyofthe1stnoteEditField.Value,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            end
            sound(y,app.Fs)
        end

        % Button pushed function: SlurButton
        function SlurButtonPushed(app, event)
            y = KS_slurnotes_acoustics(app.BPMEditField.Value,app.syncopatednote1EditField.Value,app.syncopatednote2EditField.Value,app.Frequencyofthe1stnoteEditField.Value,app.Frequencyofthe2ndnoteEditField.Value,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);
        end

        % Button pushed function: GlissandiButton
        function GlissandiButtonPushed(app, event)
            y = KS_Glissandi_acoustics(app.BPMEditField.Value,app.syncopatednote1EditField.Value,app.syncopatednote2EditField.Value,app.Frequencyofthe1stnoteEditField.Value,app.Frequencyofthe2ndnoteEditField.Value,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);
        end

        % Button pushed function: VibratoButton
        function VibratoButtonPushed(app, event)
            y = KS_vibrato_singlenotes_acoustics(app.BPMEditField.Value,app.syncopatednote1EditField.Value,app.Frequencyofthe1stnoteEditField.Value,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.D,app.Mu);
            sound(y,app.Fs)
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,0,0,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);
        end

        % Button pushed function: DmButton
        function DmButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,2,1,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);
        end

        % Button pushed function: EmButton
        function EmButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,4,1,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);            
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,5,0,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);             
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,7,0,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);                     
        end

        % Button pushed function: AmButton
        function AmButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,9,1,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);              
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            y = KS_chord_acoustics(60,4,11,0,0,app.Fs,app.dynamicsparameterKnob.Value,app.decaystretchingKnob.Value,app.Mu);
            sound(y,app.Fs);             
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
        %% global parameter
        a = round(rand(1,30)*11) ;          %random number
        syn_min = app.MinimalsegementEditField.Value;                        %minimal syncopated note
        A = [1, 2, 4, 8];
        A = syn_min*A;
        
        
        %% input
        BPM = app.BPMEditField.Value;                           %beats per minute
        R   = app.dynamicsparameterKnob.Value;                         %dynamics parameter R
        S   = app.decaystretchingKnob.Value;                          %the decay stretching factor
        Nchord = app.ChordnumberEditField.Value;                        %number of notes
        type_c = app.Chordtype0forMajorand1forMinorEditField.Value;                         %Major or Minor chord
        
        %% create chord sequency
        N = 0;                              % initialise N
        syn = zeros(1,Nchord);                  % initialise syncopated note
        for i = 1:Nchord
                random_num = A(randi(numel(A),1,1));
                N0 = random_num*60/BPM*app.Fs;
                N = N + N0;
                syn(i) = random_num;
                y = KS_chord_acoustics(BPM,syn(i),a(i),type_c,0,app.Fs,R,S,app.Mu);
                sound(y,app.Fs);
        end
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
        %% global parameter
        fA = 440;                           %standard frequency in Hz
        fC = fA*2^(3/12)/4;                 %frequency of low C in guitar
        syn_min = app.MinimalsegementEditField_2.Value;                      %Minimal syncopated note
        pitch_c = [0 4 5 7 9];              %pitch of chord
        pitch_n = [0 2 4 5 7 9];            %pitch of notes
        A = [1, 2, 4];                      %syncopated notes
        
        %% input
        NBar = app.BarnumberEditField.Value;                           %number of bar
        BPM = app.BPMEditField.Value;                           %beats per minute
        R   = app.dynamicsparameterKnob.Value;                         %dynamics parameter R
        S   = app.decaystretchingKnob.Value;                          %the decay stretching factor
        
        %% calculate parameter
        Nb = 60/BPM*app.Fs;                     %number per beats
        fre_n   = fC*2.^(pitch_n/12);       %frequency of notes
        random_c = pitch_c(randi(numel(pitch_c),8,1));%chord sequency
        random_n = pitch_n(randi(numel(pitch_n),2*NBar*4,1));%chord sequency
        A = syn_min*A;                      %actual syncopated notes
        Nn = length(random_n);              %initialise number of notes
        N0 = zeros(Nn,1);                   %initialise sample number of notes
        
        %% create actual notes based on chords length
        N = 0;                              %initialise N
        for p = 1:Nn
            random_num = A(randi(numel(A),1,1));
            N0 (p) = random_num*Nb;
            N = N + N0(p);
            if N >= Nb*NBar*4
                break
            end
        end
        N0 = N0(N0~=0);
        N0 = floor(N0);
        Nn_exact = length(N0);
        
        %% chords bank
        y0 = KS_chord_acoustics(BPM,4,pitch_c(1),0,0,app.Fs,R,S,app.Mu);
        L = length(y0);
        y = zeros(L,10);
        y(:,0+1)=KS_chord_acoustics(BPM,4,0,0,0,app.Fs,R,S,app.Mu);
        y(:,4+1)=KS_chord_acoustics(BPM,4,4,1,0,app.Fs,R,S,app.Mu);
        y(:,5+1)=KS_chord_acoustics(BPM,4,5,0,0,app.Fs,R,S,app.Mu);
        y(:,7+1)=KS_chord_acoustics(BPM,4,7,0,0,app.Fs,R,S,app.Mu);
        y(:,9+1)=KS_chord_acoustics(BPM,4,9,1,0,app.Fs,R,S,app.Mu);
        
        %% notes bank
        x0 = KS_singlenotes_acoustics(BPM,4,fre_n(1),app.Fs,R,S,app.Mu);
        M  = length(x0);
        x  = zeros(M,10);
        x(:,0+1) = KS_singlenotes_acoustics(BPM,4,fre_n(1),app.Fs,R,S,app.Mu);
        x(:,2+1) = KS_singlenotes_acoustics(BPM,4,fre_n(2),app.Fs,R,S,app.Mu);
        x(:,4+1) = KS_singlenotes_acoustics(BPM,4,fre_n(3),app.Fs,R,S,app.Mu);
        x(:,5+1) = KS_singlenotes_acoustics(BPM,4,fre_n(4),app.Fs,R,S,app.Mu);
        x(:,7+1) = KS_singlenotes_acoustics(BPM,4,fre_n(5),app.Fs,R,S,app.Mu);
        x(:,9+1) = KS_singlenotes_acoustics(BPM,4,fre_n(6),app.Fs,R,S,app.Mu);
        
        %% create chord sequency
        y2 = zeros(Nb*NBar*4+L,1);
        for i = 1:NBar
            for j = 1:4   
                y2(((i-1)*4+j-1)*Nb+1:((i-1)*4+j-1)*Nb+L,1)=y2(((i-1)*4+j-1)*Nb+1:((i-1)*4+j-1)*Nb+L,1)+y(:,random_c(i)+1);
            end
        end
        
        %% create notes
        x2 = zeros(sum(N0)+M,1);
        for q = 1:Nn_exact
            if q == 1
                x2(1:M,1)= x2(1:M,1)+x(:,random_n(q)+1);
            else
            x2(sum(N0(1:q))+1:sum(N0(1:q))+M,1)=x2(sum(N0(1:q))+1:sum(N0(1:q))+M,1)+x(:,random_n(q)+1);
            end
        end
        
        %% Normalise
        s = x2(1:Nb*NBar*4+L)+y2*0.75;
        s = s/max(abs(s));
        sound(s,app.Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.KarplusStrongAlgorithmLabel = uilabel(app.UIFigure);
            app.KarplusStrongAlgorithmLabel.Text = 'Karplus-Strong Algorithm';
            app.SinglenotesynthesisLabel = uilabel(app.UIFigure);
            app.SinglenotesynthesisLabel.Text = 'Single note synthesis';
            app.ChordsynthesisAcousticsonlyLabel = uilabel(app.UIFigure);
            app.ChordsynthesisAcousticsonlyLabel.Text = 'Chord synthesis（Acoustics only）';
            app.AutomaticcompositionAcousticsonlyLabel = uilabel(app.UIFigure);
            app.AutomaticcompositionAcousticsonlyLabel.Text = 'Automatic composition（Acoustics only）';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DmButton = uibutton(app.UIFigure, 'push');
            app.DmButton.ButtonPushedFcn = createCallbackFcn(app, @DmButtonPushed, true);
            app.DmButton.Text = 'Dm';
            app.EmButton = uibutton(app.UIFigure, 'push');
            app.EmButton.ButtonPushedFcn = createCallbackFcn(app, @EmButtonPushed, true);
            app.EmButton.Text = 'Em';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.AmButton = uibutton(app.UIFigure, 'push');
            app.AmButton.ButtonPushedFcn = createCallbackFcn(app, @AmButtonPushed, true);
            app.AmButton.Text = 'Am';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.PlayingSkillSynthesisAcousticsonlyLabel = uilabel(app.UIFigure);
            app.PlayingSkillSynthesisAcousticsonlyLabel.Text = 'Playing Skill Synthesis（Acoustics only）';
            app.SlurButton = uibutton(app.UIFigure, 'push');
            app.SlurButton.ButtonPushedFcn = createCallbackFcn(app, @SlurButtonPushed, true);
            app.SlurButton.Text = 'Slur';
            app.GlissandiButton = uibutton(app.UIFigure, 'push');
            app.GlissandiButton.ButtonPushedFcn = createCallbackFcn(app, @GlissandiButtonPushed, true);
            app.GlissandiButton.Text = 'Glissandi';
            app.VibratoButton = uibutton(app.UIFigure, 'push');
            app.VibratoButton.ButtonPushedFcn = createCallbackFcn(app, @VibratoButtonPushed, true);
            app.VibratoButton.Text = 'Vibrato';
            app.RandomChordslineLabel = uilabel(app.UIFigure);
            app.RandomChordslineLabel.Text = 'Random Chords line';
            app.RandomMusicLabel = uilabel(app.UIFigure);
            app.RandomMusicLabel.Text = 'Random Music';
            app.GuitartypeSwitchLabel = uilabel(app.UIFigure);
            app.GuitartypeSwitchLabel.Text = 'Guitar type';
            app.GuitartypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.GuitartypeSwitch.Items = {'Acoustics', 'Electric'};
            app.GuitartypeSwitch.Value = 'Acoustics';
            app.Frequencyofthe1stnoteEditFieldLabel = uilabel(app.UIFigure);
            app.Frequencyofthe1stnoteEditFieldLabel.Text = 'Frequency of the 1st note';
            app.Frequencyofthe1stnoteEditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequencyofthe1stnoteEditField.Value = 110;
            app.dynamicsparameterKnobLabel = uilabel(app.UIFigure);
            app.dynamicsparameterKnobLabel.Text = 'dynamics parameter';
            app.dynamicsparameterKnob = uiknob(app.UIFigure, 'continuous');
            app.dynamicsparameterKnob.Limits = [0 1];
            app.dynamicsparameterKnob.Value = 0.95;
            app.decaystretchingKnobLabel = uilabel(app.UIFigure);
            app.decaystretchingKnobLabel.Text = 'decay stretching';
            app.decaystretchingKnob = uiknob(app.UIFigure, 'continuous');
            app.decaystretchingKnob.Limits = [0 1];
            app.decaystretchingKnob.Value = 0.95;
            app.Frequencyofthe2ndnoteEditFieldLabel = uilabel(app.UIFigure);
            app.Frequencyofthe2ndnoteEditFieldLabel.Text = 'Frequency of the 2nd note';
            app.Frequencyofthe2ndnoteEditField = uieditfield(app.UIFigure, 'numeric');
            app.Frequencyofthe2ndnoteEditField.Value = 220;
            app.BPMEditFieldLabel = uilabel(app.UIFigure);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.BPMEditField.Value = 60;
            app.syncopatednote1EditFieldLabel = uilabel(app.UIFigure);
            app.syncopatednote1EditFieldLabel.Text = 'syncopated note 1';
            app.syncopatednote1EditField = uieditfield(app.UIFigure, 'numeric');
            app.syncopatednote1EditField.Value = 2;
            app.syncopatednote2EditFieldLabel = uilabel(app.UIFigure);
            app.syncopatednote2EditFieldLabel.Text = 'syncopated note 2';
            app.syncopatednote2EditField = uieditfield(app.UIFigure, 'numeric');
            app.syncopatednote2EditField.Value = 4;
            app.PleaseputyouraudiofileandAppinthesamefolderLabel_2 = uilabel(app.UIFigure);
            app.PleaseputyouraudiofileandAppinthesamefolderLabel_2.Text = 'syncopated note: 0.5 1 2 4.....';
            app.ChordnumberEditFieldLabel = uilabel(app.UIFigure);
            app.ChordnumberEditFieldLabel.Text = 'Chord number';
            app.ChordnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChordnumberEditField.Value = 30;
            app.BarnumberEditFieldLabel = uilabel(app.UIFigure);
            app.BarnumberEditFieldLabel.Text = 'Bar number';
            app.BarnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.BarnumberEditField.Value = 8;
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.MinimalsegementEditFieldLabel = uilabel(app.UIFigure);
            app.MinimalsegementEditFieldLabel.Text = 'Minimal segement';
            app.MinimalsegementEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimalsegementEditField.Value = 0.5;
            app.Chordtype0forMajorand1forMinorLabel = uilabel(app.UIFigure);
            app.Chordtype0forMajorand1forMinorLabel.Text = {'Chord type'; '(0 for Major and 1 for Minor)'};
            app.Chordtype0forMajorand1forMinorEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlayButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.MinimalsegementEditField_2Label = uilabel(app.UIFigure);
            app.MinimalsegementEditField_2Label.Text = 'Minimal segement';
            app.MinimalsegementEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MinimalsegementEditField_2.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KS_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end